classdef CFD_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        InletVelocityEditField          matlab.ui.control.NumericEditField
        InletVelocitymsEditFieldLabel   matlab.ui.control.Label
        DisplayOptionsWIPLabel          matlab.ui.control.Label
        PlottingVariablesTree           matlab.ui.container.CheckBoxTree
        ParamstoDisplayNode             matlab.ui.container.TreeNode
        DensityNode                     matlab.ui.container.TreeNode
        XVelocityNode                   matlab.ui.container.TreeNode
        YVelocityNode                   matlab.ui.container.TreeNode
        ENode                           matlab.ui.container.TreeNode
        ArtificialViscEditField         matlab.ui.control.NumericEditField
        ArtificialViscEditFieldLabel    matlab.ui.control.Label
        EndTimesEditField               matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel          matlab.ui.control.Label
        BoundaryDropDown                matlab.ui.control.DropDown
        BoundaryDropDownLabel           matlab.ui.control.Label
        SolverParametersLabel           matlab.ui.control.Label
        CFLEditField                    matlab.ui.control.NumericEditField
        CFLEditFieldLabel               matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        FlowVisualiser                  matlab.ui.control.UIAxes
        InterpolationTab                matlab.ui.container.Tab
        InterpolatedGridSizeYEditField  matlab.ui.control.NumericEditField
        InterpolatedGridSizeXEditField  matlab.ui.control.NumericEditField
        InterpolatedGridSizeXYEditFieldLabel  matlab.ui.control.Label
        VariabletoDisplayDropDown       matlab.ui.control.DropDown
        VariabletoDisplayDropDownLabel  matlab.ui.control.Label
        InterpolateButton               matlab.ui.control.Button
        InterpolatedFieldVisualiser     matlab.ui.control.UIAxes
        SimulationTerminatedLabel       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        IterationNumberField            matlab.ui.control.NumericEditField
        IterationLabel                  matlab.ui.control.Label
        CoordinatesofLeadingEdgeY       matlab.ui.control.NumericEditField
        CoordinatesofLeadingEdgeX       matlab.ui.control.NumericEditField
        PositionofLeadingEdgexyLabel    matlab.ui.control.Label
        NACA4DigitCodeEditField         matlab.ui.control.EditField
        NACA4DigitCodeEditFieldLabel    matlab.ui.control.Label
        AngleofAttackEditField          matlab.ui.control.NumericEditField
        AngleofAttackEditFieldLabel     matlab.ui.control.Label
        ChordLengthEditField            matlab.ui.control.NumericEditField
        ChordLengthEditFieldLabel       matlab.ui.control.Label
        YGridEditField                  matlab.ui.control.NumericEditField
        YDomainEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XGridEditField                  matlab.ui.control.NumericEditField
        XDomainEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        ChordmLabel                     matlab.ui.control.Label
        GridSizecellsLabel              matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        DomainSizeLabel                 matlab.ui.control.Label
        DefineMeshLabel                 matlab.ui.control.Label
        DefineAerofoilLabel             matlab.ui.control.Label
        SimulationTimeField             matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        TerminateSimulationButton       matlab.ui.control.Button
        PauseSimulationButton           matlab.ui.control.StateButton
        NewSimulationButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        TerminateSimProperty % property to tell main code loop to stop if terminate app button pressed
        PlottingVariables
        simData % stores simulation data for access while sim is paused or after termination
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewSimulationButton
        function NewSimulationButtonPushed(app, event)
            if isempty(app.NACA4DigitCodeEditField.Value)
                uialert(app.UIFigure, "You must select a NACA aerofoil code.", "Input Required!")
                return
            end

            % activate/disable required app components
            app.NewSimulationButton.Enable = "off";
            app.NewSimulationButton.Text = "Simulation Running...";

            app.FlowVisualiser.Visible = "on";
            app.FlowVisualiser.Toolbar.Visible = "on";
            app.SimulationTerminatedLabel.Visible = "off";

            app.TerminateSimProperty = false;
            app.TerminateSimulationButton.Enable = "on";
            
            app.PauseSimulationButton.Enable = "on";

            app.PlottingVariablesTree.Enable = "off";

            try
                GPT(app)
            catch me
                uialert(app.UIFigure, getReport(me), "Error!","Interpreter","html")
            end

            % cleanup after operation
            app.NewSimulationButton.Enable = "on";
            app.NewSimulationButton.Text = "New Simulation";
            app.TerminateSimulationButton.Enable = "off";
            app.PauseSimulationButton.Enable = "off";
        end

        % Button pushed function: TerminateSimulationButton
        function TerminateSimulationButtonPushed(app, event)
            app.TerminateSimProperty = true;
            app.TerminateSimulationButton.Enable = "off";
            app.SimulationTerminatedLabel.Visible = "on";
            
            app.PauseSimulationButton.Value = false;
            app.PauseSimulationButton.Text = 'Pause Simulation';
            app.PauseSimulationButton.FontWeight = "normal";
            app.PauseSimulationButton.Enable = "off";

            app.NewSimulationButton.Text = "New Simulation";
            app.NewSimulationButton.Enable = "on"; 
            
            app.PlottingVariablesTree.Enable = "on";
        end

        % Value changed function: PauseSimulationButton
        function PauseSimulationButtonValueChanged(app, event)
            if app.PauseSimulationButton.Value % true when simulation paused
                app.PauseSimulationButton.Text = 'Resume Simulation';
                app.PauseSimulationButton.FontWeight = "bold";
                app.NewSimulationButton.Text = "Simulation Paused...";
                app.PlottingVariablesTree.Enable = "on";
            else
                app.PauseSimulationButton.Text = 'Pause Simulation';
                app.PauseSimulationButton.FontWeight = "normal";
                app.NewSimulationButton.Text = "Simulation Running...";
                app.PlottingVariablesTree.Enable = "off";
            end
        end

        % Callback function: PlottingVariablesTree
        function PlottingVariablesTreeCheckedNodesChanged(app, event)
            checkedNodes = app.PlottingVariablesTree.CheckedNodes; % use app.PlottingVariablesTree.CheckedNodes in main code loop
            for i = 1: length(checkedNodes)
                switch checkedNodes(i)% plotting variable display options: Density, X vel, Y vel, E
                    case "Density"
                        app.PlottingVariables.Density = true;
                    case "X Velocity"
                        app.PlottingVariables.XVel = true;
                    case "Y Velocity"
                        app.PlottingVariables.YVel = true;
                    case "Energy"
                        app.PlottingVariables.E = true;
                end
            end
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            app.InterpolatedFieldVisualiser.Visible = "on";
            switch app.VariabletoDisplayDropDown.Value
                case "Density"
                    dataToInterpolate = app.simData.density;
                case "X Velocity"
                    dataToInterpolate = app.simData.xVelocity;
                case "Y Velocity"
                    dataToInterpolate = app.simData.yVelocity;
                case "Energy"
                    dataToInterpolate = app.simData.energy;
            end

            % Problem / grid setup - TODO this is repeated in GPT.m, could
            % try to avoid duplication?
            Lx = app.XDomainEditField.Value; Ly = app.YDomainEditField.Value;                                     % domain size
            Nx = app.InterpolatedGridSizeXEditField.Value; Ny = app.InterpolatedGridSizeYEditField.Value;         % grid cells in x/y
            dx = Lx / Nx; dy = Ly / Ny;
            x = linspace(dx/2, Lx-dx/2, Nx);
            y = linspace(dy/2, Ly-dy/2, Ny);
            [interpolatedGridX, interpolatedGridY] = meshgrid(x, y);
            
            interpolatedField = interp2(app.simData.meshGridXY(:,:,1), app.simData.meshGridXY(:,:,2), dataToInterpolate, interpolatedGridX, interpolatedGridY);
            
            imagesc(app.InterpolatedFieldVisualiser, [x(1) Lx], [y(1) Ly], interpolatedField)
            set(app.InterpolatedFieldVisualiser,'YDir','normal');
            title(app.InterpolatedFieldVisualiser, sprintf('Interpolated %s at t = %.4f', app.VariabletoDisplayDropDown.Value, app.SimulationTimeField.Value));
            colorbar(app.InterpolatedFieldVisualiser);
            drawnow;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {602, 602, 602};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {602, 602};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x', 252};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CFLEditFieldLabel = uilabel(app.LeftPanel);
            app.CFLEditFieldLabel.Text = 'CFL';
            app.CFLEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CFLEditField.Limits = [0 1];
            app.CFLEditField.Value = 0.2;
            app.SolverParametersLabel = uilabel(app.LeftPanel);
            app.SolverParametersLabel.Text = 'Solver Parameters';
            app.BoundaryDropDownLabel = uilabel(app.LeftPanel);
            app.BoundaryDropDownLabel.Text = 'Boundary';
            app.BoundaryDropDown = uidropdown(app.LeftPanel);
            app.BoundaryDropDown.Items = {'outflow', 'periodic', 'wall'};
            app.BoundaryDropDown.Value = 'outflow';
            app.EndTimesEditFieldLabel = uilabel(app.LeftPanel);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndTimesEditField.Limits = [0 Inf];
            app.EndTimesEditField.Value = 5;
            app.ArtificialViscEditFieldLabel = uilabel(app.LeftPanel);
            app.ArtificialViscEditFieldLabel.Text = 'Artificial Visc.';
            app.ArtificialViscEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ArtificialViscEditField.Limits = [0 1];
            app.ArtificialViscEditField.Value = 0.01;
            app.PlottingVariablesTree = uitree(app.LeftPanel, 'checkbox');
            app.ParamstoDisplayNode = uitreenode(app.PlottingVariablesTree);
            app.ParamstoDisplayNode.Text = 'Params to Display';
            app.DensityNode = uitreenode(app.ParamstoDisplayNode);
            app.DensityNode.Text = 'Density';
            app.XVelocityNode = uitreenode(app.ParamstoDisplayNode);
            app.XVelocityNode.Text = 'X Velocity';
            app.YVelocityNode = uitreenode(app.ParamstoDisplayNode);
            app.YVelocityNode.Text = 'Y Velocity';
            app.ENode = uitreenode(app.ParamstoDisplayNode);
            app.ENode.Text = 'E';
            % Assign Checked Nodes
            app.PlottingVariablesTree.CheckedNodes = [app.DensityNode];
            % Assign Checked Nodes
            app.PlottingVariablesTree.CheckedNodesChangedFcn = createCallbackFcn(app, @PlottingVariablesTreeCheckedNodesChanged, true);
            app.DisplayOptionsWIPLabel = uilabel(app.LeftPanel);
            app.DisplayOptionsWIPLabel.Text = 'Display Options (WIP)';
            app.InletVelocitymsEditFieldLabel = uilabel(app.LeftPanel);
            app.InletVelocitymsEditFieldLabel.Text = 'Inlet Velocity (m/s)';
            app.InletVelocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InletVelocityEditField.Value = 1;
            app.CenterPanel = uipanel(app.GridLayout);
            app.SimulationTerminatedLabel = uilabel(app.CenterPanel);
            app.SimulationTerminatedLabel.Visible = 'off';
            app.SimulationTerminatedLabel.Text = 'Simulation Terminated';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.FlowVisualiser = uiaxes(app.SimulationTab);
            title(app.FlowVisualiser, 'Flow')
            xlabel(app.FlowVisualiser, 'X')
            ylabel(app.FlowVisualiser, 'Y')
            zlabel(app.FlowVisualiser, 'Z')
            app.FlowVisualiser.Visible = 'off';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.InterpolatedFieldVisualiser = uiaxes(app.InterpolationTab);
            title(app.InterpolatedFieldVisualiser, 'Title')
            xlabel(app.InterpolatedFieldVisualiser, 'X')
            ylabel(app.InterpolatedFieldVisualiser, 'Y')
            zlabel(app.InterpolatedFieldVisualiser, 'Z')
            app.InterpolatedFieldVisualiser.Visible = 'off';
            app.InterpolateButton = uibutton(app.InterpolationTab, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.VariabletoDisplayDropDownLabel = uilabel(app.InterpolationTab);
            app.VariabletoDisplayDropDownLabel.Text = 'Variable to Display';
            app.VariabletoDisplayDropDown = uidropdown(app.InterpolationTab);
            app.VariabletoDisplayDropDown.Items = {'Density', 'X Velocity', 'Y Velocity', 'Energy'};
            app.VariabletoDisplayDropDown.Value = 'Density';
            app.InterpolatedGridSizeXYEditFieldLabel = uilabel(app.InterpolationTab);
            app.InterpolatedGridSizeXYEditFieldLabel.Text = 'Interpolated Grid Size (X, Y)';
            app.InterpolatedGridSizeXEditField = uieditfield(app.InterpolationTab, 'numeric');
            app.InterpolatedGridSizeXEditField.Limits = [0 Inf];
            app.InterpolatedGridSizeXEditField.Value = 500;
            app.InterpolatedGridSizeYEditField = uieditfield(app.InterpolationTab, 'numeric');
            app.InterpolatedGridSizeYEditField.Limits = [0 Inf];
            app.InterpolatedGridSizeYEditField.Value = 500;
            app.RightPanel = uipanel(app.GridLayout);
            app.NewSimulationButton = uibutton(app.RightPanel, 'push');
            app.NewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NewSimulationButtonPushed, true);
            app.NewSimulationButton.Text = 'New Simulation';
            app.PauseSimulationButton = uibutton(app.RightPanel, 'state');
            app.PauseSimulationButton.ValueChangedFcn = createCallbackFcn(app, @PauseSimulationButtonValueChanged, true);
            app.PauseSimulationButton.Enable = 'off';
            app.PauseSimulationButton.Text = 'Pause Simulation';
            app.TerminateSimulationButton = uibutton(app.RightPanel, 'push');
            app.TerminateSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateSimulationButtonPushed, true);
            app.TerminateSimulationButton.Enable = 'off';
            app.TerminateSimulationButton.Text = 'Terminate Simulation';
            app.SimulationTimeEditFieldLabel = uilabel(app.RightPanel);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeField = uieditfield(app.RightPanel, 'numeric');
            app.SimulationTimeField.Limits = [0 Inf];
            app.SimulationTimeField.Editable = 'off';
            app.DefineAerofoilLabel = uilabel(app.RightPanel);
            app.DefineAerofoilLabel.Text = 'Define Aerofoil';
            app.DefineMeshLabel = uilabel(app.RightPanel);
            app.DefineMeshLabel.Text = 'Define Mesh';
            app.DomainSizeLabel = uilabel(app.RightPanel);
            app.DomainSizeLabel.Text = 'Domain Size (m)';
            app.NumberofPointsEditFieldLabel = uilabel(app.RightPanel);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.degLabel = uilabel(app.RightPanel);
            app.degLabel.Text = '(°)';
            app.GridSizecellsLabel = uilabel(app.RightPanel);
            app.GridSizecellsLabel.Text = 'Grid Size (cells)';
            app.ChordmLabel = uilabel(app.RightPanel);
            app.ChordmLabel.Text = '(m)';
            app.NumberofPointsEditField = uieditfield(app.RightPanel, 'numeric');
            app.NumberofPointsEditField.Limits = [0 Inf];
            app.NumberofPointsEditField.RoundFractionalValues = 'on';
            app.NumberofPointsEditField.Value = 132;
            app.XEditFieldLabel = uilabel(app.RightPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XDomainEditField = uieditfield(app.RightPanel, 'numeric');
            app.XDomainEditField.Limits = [0 Inf];
            app.XDomainEditField.RoundFractionalValues = 'on';
            app.XDomainEditField.Value = 4;
            app.XGridEditField = uieditfield(app.RightPanel, 'numeric');
            app.XGridEditField.Limits = [0 Inf];
            app.XGridEditField.RoundFractionalValues = 'on';
            app.XGridEditField.Value = 200;
            app.YEditFieldLabel = uilabel(app.RightPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YDomainEditField = uieditfield(app.RightPanel, 'numeric');
            app.YDomainEditField.Limits = [0 Inf];
            app.YDomainEditField.RoundFractionalValues = 'on';
            app.YDomainEditField.Value = 2;
            app.YGridEditField = uieditfield(app.RightPanel, 'numeric');
            app.YGridEditField.Limits = [0 Inf];
            app.YGridEditField.RoundFractionalValues = 'on';
            app.YGridEditField.Value = 200;
            app.ChordLengthEditFieldLabel = uilabel(app.RightPanel);
            app.ChordLengthEditFieldLabel.Text = 'Chord Length';
            app.ChordLengthEditField = uieditfield(app.RightPanel, 'numeric');
            app.ChordLengthEditField.Limits = [0 Inf];
            app.ChordLengthEditField.Value = 1;
            app.AngleofAttackEditFieldLabel = uilabel(app.RightPanel);
            app.AngleofAttackEditFieldLabel.Text = 'Angle of Attack';
            app.AngleofAttackEditField = uieditfield(app.RightPanel, 'numeric');
            app.AngleofAttackEditField.Limits = [-180 180];
            app.NACA4DigitCodeEditFieldLabel = uilabel(app.RightPanel);
            app.NACA4DigitCodeEditFieldLabel.Text = 'NACA 4 Digit Code';
            app.NACA4DigitCodeEditField = uieditfield(app.RightPanel, 'text');
            app.NACA4DigitCodeEditField.CharacterLimits = [4 4];
            app.NACA4DigitCodeEditField.Placeholder = 'MPXX';
            app.NACA4DigitCodeEditField.Value = '0012';
            app.PositionofLeadingEdgexyLabel = uilabel(app.RightPanel);
            app.PositionofLeadingEdgexyLabel.Text = 'Grid Position of Leading Edge (x%,y%)';
            app.CoordinatesofLeadingEdgeX = uieditfield(app.RightPanel, 'numeric');
            app.CoordinatesofLeadingEdgeX.Limits = [0 100];
            app.CoordinatesofLeadingEdgeX.Value = 25;
            app.CoordinatesofLeadingEdgeY = uieditfield(app.RightPanel, 'numeric');
            app.CoordinatesofLeadingEdgeY.Limits = [0 100];
            app.CoordinatesofLeadingEdgeY.Value = 50;
            app.IterationLabel = uilabel(app.RightPanel);
            app.IterationLabel.Text = 'Iteration';
            app.IterationNumberField = uieditfield(app.RightPanel, 'numeric');
            app.IterationNumberField.Limits = [0 Inf];
            app.IterationNumberField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CFD_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end